classdef final6013150 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Joint1SliderLabel       matlab.ui.control.Label
        Joint1SliderL           matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint2SliderL           matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint3SliderL           matlab.ui.control.Slider
        Joint4SliderLabel       matlab.ui.control.Label
        Joint4SliderL           matlab.ui.control.Slider
        Joint5SliderLabel       matlab.ui.control.Label
        Joint5SliderL           matlab.ui.control.Slider
        Joint6SliderLabel       matlab.ui.control.Label
        Joint6SliderL           matlab.ui.control.Slider
        Joint7SliderLabel       matlab.ui.control.Label
        Joint7SliderL           matlab.ui.control.Slider
        Joint1SliderLabel_2     matlab.ui.control.Label
        Joint1SliderR           matlab.ui.control.Slider
        Joint2SliderLabel_2     matlab.ui.control.Label
        Joint2SliderR           matlab.ui.control.Slider
        Joint3SliderLabel_2     matlab.ui.control.Label
        Joint3SliderR           matlab.ui.control.Slider
        Joint4SliderLabel_2     matlab.ui.control.Label
        Joint4SliderR           matlab.ui.control.Slider
        Joint5SliderLabel_2     matlab.ui.control.Label
        Joint5SliderR           matlab.ui.control.Slider
        Joint6SliderLabel_2     matlab.ui.control.Label
        Joint6SliderR           matlab.ui.control.Slider
        Joint7SliderLabel_2     matlab.ui.control.Label
        Joint7SliderR           matlab.ui.control.Slider
        LeftLabel               matlab.ui.control.Label
        RightLabel              matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        Angle1EditFieldLabel    matlab.ui.control.Label
        Angle1EditFieldL        matlab.ui.control.NumericEditField
        Angle2EditFieldLabel    matlab.ui.control.Label
        Angle2EditFieldL        matlab.ui.control.NumericEditField
        Angle3EditFieldLabel    matlab.ui.control.Label
        Angle3EditFieldL        matlab.ui.control.NumericEditField
        Angle4EditFieldLabel    matlab.ui.control.Label
        Angle4EditFieldL        matlab.ui.control.NumericEditField
        Angle5EditFieldLabel    matlab.ui.control.Label
        Angle5EditFieldL        matlab.ui.control.NumericEditField
        Angle6EditFieldLabel    matlab.ui.control.Label
        Angle6EditFieldL        matlab.ui.control.NumericEditField
        Angle7EditFieldLabel    matlab.ui.control.Label
        Angle7EditFieldL        matlab.ui.control.NumericEditField
        Angle1EditField_2Label  matlab.ui.control.Label
        Angle1EditFieldR        matlab.ui.control.NumericEditField
        Angle2EditField_2Label  matlab.ui.control.Label
        Angle2EditFieldR        matlab.ui.control.NumericEditField
        Angle3EditField_2Label  matlab.ui.control.Label
        Angle3EditFieldR        matlab.ui.control.NumericEditField
        Angle4EditField_2Label  matlab.ui.control.Label
        Angle4EditFieldR        matlab.ui.control.NumericEditField
        Angle5EditField_2Label  matlab.ui.control.Label
        Angle5EditFieldR        matlab.ui.control.NumericEditField
        Angle6EditField_2Label  matlab.ui.control.Label
        Angle6EditFieldR        matlab.ui.control.NumericEditField
        Angle7EditField_2Label  matlab.ui.control.Label
        Angle7EditFieldR        matlab.ui.control.NumericEditField
        StatusLampLabel         matlab.ui.control.Label
        StatusLamp              matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        base        = [0;0;0];
        heightRobot = 571;
        armDistance = 260/2;
        widghtRobot = 421;
        link1Lenght = 166;
        link2Lenght = 251.5/2;
        link3Lenght = 251.5/2;
        link4Lenght = 265/2;
        link5Lenght = 265/2;
        link6Lenght = 36;
        
        leftAngle1   = 0;
        leftAngle2   = 0;
        leftAngle3   = 0;
        leftAngle4   = 0;
        leftAngle5   = 0;
        leftAngle6   = 0;
        leftAngle7   = 0;
        rightAngle1  = 0;
        rightAngle2  = 0;
        rightAngle3  = 0;
        rightAngle4  = 0;
        rightAngle5  = 0;
        rightAngle6  = 0;
        rightAngle7  = 0;
        
        
        leftAngleNew1   = 0;
        leftAngleNew2   = 0;
        leftAngleNew3   = 0;
        leftAngleNew4   = 0;
        leftAngleNew5   = 0;
        leftAngleNew6   = 0;
        leftAngleNew7   = 0;
        rightAngleNew1  = 0;
        rightAngleNew2  = 0;
        rightAngleNew3  = 0;
        rightAngleNew4  = 0;
        rightAngleNew5  = 0;
        rightAngleNew6  = 0;
        rightAngleNew7  = 0;
        
        state=0;
        countI1=0;
        countI2=0;
        countI3=0;
        countI4=0;
        countI5=0;
        countI6=0;
        countI7=0;
        countI8=0;
        countI9=0;
        countI10=0;
        countI11=0;
        countI12=0;
        countI13=0;
        countI14=0;
    end
    
    methods (Access = public)
        
        function plotGraph(app)
            %transition matrix
            transitionMatrixBase    = [ 1 0 0 0 ;
                                        0 1 0 0 ;
                                        0 0 1 app.heightRobot ;
                                        0 0 0 1 ];
            transitionMatrixWidght  = [ 1 0 0 0 ;
                                        0 1 0 app.widghtRobot ;
                                        0 0 1 0 ;
                                        0 0 0 1 ];
            %left side
            transitionMatrixWidght_LeftJoint1   = [ cosd(app.leftAngle1) -sind(app.leftAngle1) 0 -app.armDistance ;
                                                    sind(app.leftAngle1) cosd(app.leftAngle1) 0 0 ;
                                                    0 0 1 0 ;
                                                    0 0 0 1 ];
            transitionMatrixLeftJoint1_2    = [ 1   0                       0                       0 ;
                                                0   cosd(app.leftAngle2)    -sind(app.leftAngle2)   0 ;
                                                0   sind(app.leftAngle2)    cosd(app.leftAngle2)    app.link1Lenght ;
                                                0   0                       0                       1 ];
            transitionMatrixLeftJoint2_3    = [ cosd(app.leftAngle3)    -sind(app.leftAngle3)   0   0 ;
                                                sind(app.leftAngle3)    cosd(app.leftAngle3)    0   0 ;
                                                0                       0                       1   app.link2Lenght ;
                                                0                       0                       0   1 ];
            transitionMatrixLeftJoint3_4    = [ 1   0                       0                       0 ;
                                                0   cosd(app.leftAngle4)    -sind(app.leftAngle4)   app.link3Lenght*sind(app.leftAngle4) ;
                                                0   sind(app.leftAngle4)    cosd(app.leftAngle4)    app.link3Lenght*cosd(app.leftAngle4) ;
                                                0   0                       0                       1 ];
            transitionMatrixLeftJoint4_5    = [ cosd(app.leftAngle5)    0   sind(app.leftAngle5)    0 ;
                                                0                       1   0                       app.link4Lenght ;
                                                -sind(app.leftAngle5)   0   cosd(app.leftAngle5)    0 ;
                                                0                       0   0                       1 ];
            transitionMatrixLeftJoint5_6    = [ 1   0                       0                       0 ;
                                                0   cosd(app.leftAngle6)    -sind(app.leftAngle6)   app.link5Lenght*cosd(app.leftAngle6) ;
                                                0   sind(app.leftAngle6)    cosd(app.leftAngle6)    -app.link5Lenght*sind(app.leftAngle6) ;
                                                0   0                       0                       1 ];
            transitionMatrixLeftJoint6_7    = [ cosd(app.leftAngle7)    0   sind(app.leftAngle7)    0 ;
                                                0                       1   0                       app.link6Lenght ;
                                                -sind(app.leftAngle7)   0   cosd(app.leftAngle7)    0 ;
                                                0                       0   0                       1 ];
            %right side
            transitionMatrixWidght_RightJoint1  = [ cosd(app.rightAngle1)   -sind(app.rightAngle1)  0   app.armDistance ;
                                                    sind(app.rightAngle1)   cosd(app.rightAngle1)   0   0 ;
                                                    0                       0                       1   0 ;
                                                    0                       0                       0   1 ];
            transitionMatrixRightJoint1_2   = [ 1   0                       0                       0 ;
                                                0   cosd(app.rightAngle2)   -sind(app.rightAngle2)  0 ;
                                                0   sind(app.rightAngle2)   cosd(app.rightAngle2)   app.link1Lenght ;
                                                0   0                       0                       1 ];
            transitionMatrixRightJoint2_3   = [ cosd(app.rightAngle3)   -sind(app.rightAngle3)  0   0 ;
                                                sind(app.rightAngle3)   cosd(app.rightAngle3)   0   0 ;
                                                0                       0                       1   app.link2Lenght ;
                                                0                       0                       0   1 ];
            transitionMatrixRightJoint3_4   = [ 1   0                       0                       0 ;
                                                0   cosd(app.rightAngle4)   -sind(app.rightAngle4)  app.link3Lenght*sind(app.rightAngle4) ;
                                                0   sind(app.rightAngle4)   cosd(app.rightAngle4)   app.link3Lenght*cosd(app.rightAngle4) ;
                                                0   0                       0                       1 ];
            transitionMatrixRightJoint4_5   = [ cosd(app.rightAngle5)   0   sind(app.rightAngle5)   0 ;
                                                0                       1   0                       app.link4Lenght ;
                                                -sind(app.rightAngle5)  0   cosd(app.rightAngle5)   0 ;
                                                0                       0   0                       1 ];
            transitionMatrixRightJoint5_6   = [ 1   0                       0                       0 ;
                                                0   cosd(app.rightAngle6)   -sind(app.rightAngle6)  app.link5Lenght*cosd(app.rightAngle6) ;
                                                0   sind(app.rightAngle6)   cosd(app.rightAngle6)   -app.link5Lenght*sind(app.rightAngle6) ;
                                                0   0                       0                       1 ];
            transitionMatrixRightJoint6_7   = [ cosd(app.rightAngle7)   0   sind(app.rightAngle7)   0 ;
                                                0                       1   0                       app.link6Lenght ;
                                                -sind(app.rightAngle7)  0   cosd(app.rightAngle7)   0 ;
                                                0                       0   0                       1 ];
            %find position
            homoTranB_H = transitionMatrixBase;
            homoTranB_W = homoTranB_H*transitionMatrixWidght;
            positionB_H = homoTranB_H*[0;0;0;1;];
            positionB_W = homoTranB_W*[0;0;0;1;];
            
            homoTranB_L1 = homoTranB_W*transitionMatrixWidght_LeftJoint1;
            homoTranB_L2 = homoTranB_L1*transitionMatrixLeftJoint1_2;
            homoTranB_L3 = homoTranB_L2*transitionMatrixLeftJoint2_3;
            homoTranB_L4 = homoTranB_L3*transitionMatrixLeftJoint3_4;
            homoTranB_L5 = homoTranB_L4*transitionMatrixLeftJoint4_5;
            homoTranB_L6 = homoTranB_L5*transitionMatrixLeftJoint5_6;
            homoTranB_L7 = homoTranB_L6*transitionMatrixLeftJoint6_7;
            positionB_L1 = homoTranB_L1*[0;0;0;1;];
            positionB_L2 = homoTranB_L2*[0;0;0;1;];
            positionB_L3 = homoTranB_L3*[0;0;0;1;];
            positionB_L4 = homoTranB_L4*[0;0;0;1;];
            positionB_L5 = homoTranB_L5*[0;0;0;1;];
            positionB_L6 = homoTranB_L6*[0;0;0;1;];
            positionB_L7 = homoTranB_L7*[0;0;0;1;];
            
            homoTranB_R1 = homoTranB_W*transitionMatrixWidght_RightJoint1;
            homoTranB_R2 = homoTranB_R1*transitionMatrixRightJoint1_2;
            homoTranB_R3 = homoTranB_R2*transitionMatrixRightJoint2_3;
            homoTranB_R4 = homoTranB_R3*transitionMatrixRightJoint3_4;
            homoTranB_R5 = homoTranB_R4*transitionMatrixRightJoint4_5;
            homoTranB_R6 = homoTranB_R5*transitionMatrixRightJoint5_6;
            homoTranB_R7 = homoTranB_R6*transitionMatrixRightJoint6_7;
            positionB_R1 = homoTranB_R1*[0;0;0;1;];
            positionB_R2 = homoTranB_R2*[0;0;0;1;];
            positionB_R3 = homoTranB_R3*[0;0;0;1;];
            positionB_R4 = homoTranB_R4*[0;0;0;1;];
            positionB_R5 = homoTranB_R5*[0;0;0;1;];
            positionB_R6 = homoTranB_R6*[0;0;0;1;];
            positionB_R7 = homoTranB_R7*[0;0;0;1;];
            
            %plot robot
            plot3(app.UIAxes, [app.base(1) positionB_H(1)], [app.base(2) positionB_H(2)], [app.base(3) positionB_H(3)], [positionB_H(1) positionB_W(1)], [positionB_H(2) positionB_W(2)], [positionB_H(3) positionB_W(3)],'-o', 'Color', '#334148', 'LineWidth', 3)
            hold (app.UIAxes,'on')
            %plot each left
            plot3(app.UIAxes, [positionB_W(1) positionB_L1(1)], [positionB_W(2) positionB_L1(2)], [positionB_W(3) positionB_L1(3)],'-o', 'Color', '#334148', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_L1(1) positionB_L2(1)], [positionB_L1(2) positionB_L2(2)], [positionB_L1(3) positionB_L2(3)],'-o', 'Color', '#355C7D', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_L2(1) positionB_L3(1)], [positionB_L2(2) positionB_L3(2)], [positionB_L2(3) positionB_L3(3)],'-o', 'Color', '#6C5B7B', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_L3(1) positionB_L4(1)], [positionB_L3(2) positionB_L4(2)], [positionB_L3(3) positionB_L4(3)],'-o', 'Color', '#C06C84', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_L4(1) positionB_L5(1)], [positionB_L4(2) positionB_L5(2)], [positionB_L4(3) positionB_L5(3)],'-o', 'Color', '#F67280', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_L5(1) positionB_L6(1)], [positionB_L5(2) positionB_L6(2)], [positionB_L5(3) positionB_L6(3)],'-o', 'Color', '#F8B195', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_L6(1) positionB_L7(1)], [positionB_L6(2) positionB_L7(2)], [positionB_L6(3) positionB_L7(3)],'-o', 'Color', '#FBE888', 'LineWidth', 3)

            %plot each right
            plot3(app.UIAxes, [positionB_W(1) positionB_R1(1)], [positionB_W(2) positionB_R1(2)], [positionB_W(3) positionB_R1(3)],'-o', 'Color', '#334148', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_R1(1) positionB_R2(1)], [positionB_R1(2) positionB_R2(2)], [positionB_R1(3) positionB_R2(3)],'-o', 'Color', '#355C7D', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_R2(1) positionB_R3(1)], [positionB_R2(2) positionB_R3(2)], [positionB_R2(3) positionB_R3(3)],'-o', 'Color', '#6C5B7B', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_R3(1) positionB_R4(1)], [positionB_R3(2) positionB_R4(2)], [positionB_R3(3) positionB_R4(3)],'-o', 'Color', '#C06C84', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_R4(1) positionB_R5(1)], [positionB_R4(2) positionB_R5(2)], [positionB_R4(3) positionB_R5(3)],'-o', 'Color', '#F67280', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_R5(1) positionB_R6(1)], [positionB_R5(2) positionB_R6(2)], [positionB_R5(3) positionB_R6(3)],'-o', 'Color', '#F8B195', 'LineWidth', 3)

            plot3(app.UIAxes, [positionB_R6(1) positionB_R7(1)], [positionB_R6(2) positionB_R7(2)], [positionB_R6(3) positionB_R7(3)],'-o', 'Color', '#FBE888', 'LineWidth', 3)

            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
            zlabel(app.UIAxes,'Z')
            hold (app.UIAxes,'off')
            grid (app.UIAxes,'on')
            
            fprintf('Left angle = %f, %f \n', app.leftAngleNew1,app.leftAngle1)
            fprintf('position = %f %f %f \n',positionB_L6(1),positionB_L6(2),positionB_L6(3))
            fprintf('i = %d %d %d %d %d %d %d %d %d %d %d %d %d %d \n', app.countI1, app.countI2, app.countI3, app.countI4, app.countI5, app.countI6, app.countI7, app.countI8, app.countI9, app.countI10, app.countI11, app.countI12, app.countI13, app.countI14)
        end
        
        function updateAngle(app)
        app.StatusLampLabel.Text = 'Process';
        app.StatusLamp.Enable = 'on';
        app.StatusLamp.Color = [0.9216 0.5882 0.7176];
        while app.countI1~=1||app.countI2~=1||app.countI3~=1||app.countI4~=1||app.countI5~=1||app.countI6~=1||app.countI7~=1||app.countI8~=1||app.countI9~=1||app.countI10~=1||app.countI11~=1||app.countI12~=1||app.countI13~=1||app.countI14~=1
            if(app.leftAngleNew1>=app.leftAngle1)
                if(app.leftAngleNew1-app.leftAngle1>=1)
                    app.leftAngle1=app.leftAngle1+1;
                elseif(app.leftAngleNew1-app.leftAngle1<1)
                    app.leftAngle1=app.leftAngleNew1;
                    app.countI1=1;
                end
            end
            if(app.leftAngleNew1<app.leftAngle1)
                if(app.leftAngle1-app.leftAngleNew1>=1)
                    app.leftAngle1=app.leftAngle1-1;
                elseif(app.leftAngle1-app.leftAngleNew1<1)
                    app.leftAngle1=app.leftAngleNew1;
                    app.countI1=1;
                end
            end
            if(app.leftAngleNew2>=app.leftAngle2)
                if(app.leftAngleNew2-app.leftAngle2>=1)
                    app.leftAngle2=app.leftAngle2+1;
                elseif(app.leftAngleNew2-app.leftAngle2<1)
                    app.leftAngle2=app.leftAngleNew2;
                    app.countI2=1;
                end
            end
            if(app.leftAngleNew2<app.leftAngle2)
                if(app.leftAngle2-app.leftAngleNew2>=1)
                    app.leftAngle2=app.leftAngle2-1;
                elseif(app.leftAngle2-app.leftAngleNew2<1)
                    app.leftAngle2=app.leftAngleNew2;
                    app.countI2=1;
                end
            end
            if(app.leftAngleNew3>=app.leftAngle3)
                if(app.leftAngleNew3-app.leftAngle3>=1)
                    app.leftAngle3=app.leftAngle3+1;
                elseif(app.leftAngleNew3-app.leftAngle3<1)
                    app.leftAngle3=app.leftAngleNew3;
                    app.countI3=1;
                end
            end
            if(app.leftAngleNew3<app.leftAngle3)
                if(app.leftAngle3-app.leftAngleNew3>=1)
                    app.leftAngle3=app.leftAngle3-1;
                elseif(app.leftAngle3-app.leftAngleNew3<1)
                    app.leftAngle3=app.leftAngleNew3;
                    app.countI3=1;
                end
            end
            if(app.leftAngleNew4>=app.leftAngle4)
                if(app.leftAngleNew4-app.leftAngle4>=1)
                    app.leftAngle4=app.leftAngle4+1;
                elseif(app.leftAngleNew4-app.leftAngle4<1)
                    app.leftAngle4=app.leftAngleNew4;
                    app.countI4=1;
                end
            end
            if(app.leftAngleNew4<app.leftAngle4)
                if(app.leftAngle4-app.leftAngleNew4>=1)
                    app.leftAngle4=app.leftAngle4-1;
                elseif(app.leftAngle4-app.leftAngleNew4<1)
                    app.leftAngle4=app.leftAngleNew4;
                    app.countI4=1;
                end
            end
            if(app.leftAngleNew5>=app.leftAngle5)
                if(app.leftAngleNew5-app.leftAngle5>=1)
                    app.leftAngle5=app.leftAngle5+1;
                elseif(app.leftAngleNew5-app.leftAngle5<1)
                    app.leftAngle5=app.leftAngleNew5;
                    app.countI5=1;
                end
            end
            if(app.leftAngleNew5<app.leftAngle5)
                if(app.leftAngle5-app.leftAngleNew5>=1)
                    app.leftAngle5=app.leftAngle5-1;
                elseif(app.leftAngle5-app.leftAngleNew5<1)
                    app.leftAngle5=app.leftAngleNew5;
                    app.countI5=1;
                end
            end
            if(app.leftAngleNew6>=app.leftAngle6)
                if(app.leftAngleNew6-app.leftAngle6>=1)
                    app.leftAngle6=app.leftAngle6+1;
                elseif(app.leftAngleNew6-app.leftAngle6<1)
                    app.leftAngle6=app.leftAngleNew6;
                    app.countI6=1;
                end
            end
            if(app.leftAngleNew6<app.leftAngle6)
                if(app.leftAngle6-app.leftAngleNew6>=1)
                    app.leftAngle6=app.leftAngle6-1;
                elseif(app.leftAngle6-app.leftAngleNew6<1)
                    app.leftAngle6=app.leftAngleNew6;
                    app.countI6=1;
                end
            end
            if(app.leftAngleNew7>=app.leftAngle7)
                if(app.leftAngleNew7-app.leftAngle7>=1)
                    app.leftAngle7=app.leftAngle7+1;
                elseif(app.leftAngleNew7-app.leftAngle7<1)
                    app.leftAngle7=app.leftAngleNew7;
                    app.countI7=1;
                end
            end
            if(app.leftAngleNew7<app.leftAngle7)
                if(app.leftAngle7-app.leftAngleNew7>=1)
                    app.leftAngle7=app.leftAngle7-1;
                elseif(app.leftAngle7-app.leftAngleNew7<1)
                    app.leftAngle7=app.leftAngleNew7;
                    app.countI7=1;
                end
            end
            if(app.rightAngleNew1>=app.rightAngle1)
                if(app.rightAngleNew1-app.rightAngle1>=1)
                    app.rightAngle1=app.rightAngle1+1;
                elseif(app.rightAngleNew1-app.rightAngle1<1)
                    app.rightAngle1=app.rightAngleNew1;
                    app.countI8=1;
                end
            end
            if(app.rightAngleNew1<app.rightAngle1)
                if(app.rightAngle1-app.rightAngleNew1>=1)
                    app.rightAngle1=app.rightAngle1-1;
                elseif(app.rightAngle1-app.rightAngleNew1<1)
                    app.rightAngle1=app.rightAngleNew1;
                    app.countI8=1;
                end
            end
            if(app.rightAngleNew2>=app.rightAngle2)
                if(app.rightAngleNew2-app.rightAngle2>=1)
                    app.rightAngle2=app.rightAngle2+1;
                elseif(app.rightAngleNew2-app.rightAngle2<1)
                    app.rightAngle2=app.rightAngleNew2;
                    app.countI9=1;
                end
            end
            if(app.rightAngleNew2<app.rightAngle2)
                if(app.rightAngle2-app.rightAngleNew2>=1)
                    app.rightAngle2=app.rightAngle2-1;
                elseif(app.rightAngle2-app.rightAngleNew2<1)
                    app.rightAngle2=app.rightAngleNew2;
                    app.countI9=1;
                end
            end
            if(app.rightAngleNew3>=app.rightAngle3)
                if(app.rightAngleNew3-app.rightAngle3>=1)
                    app.rightAngle3=app.rightAngle3+1;
                elseif(app.rightAngleNew3-app.rightAngle3<1)
                    app.rightAngle3=app.rightAngleNew3;
                    app.countI10=1;
                end
            end
            if(app.rightAngleNew3<app.rightAngle3)
                if(app.rightAngle3-app.rightAngleNew3>=1)
                    app.rightAngle3=app.rightAngle3-1;
                elseif(app.rightAngle3-app.rightAngleNew3<1)
                    app.rightAngle3=app.rightAngleNew3;
                    app.countI10=1;
                end
            end
            if(app.rightAngleNew4>=app.rightAngle4)
                if(app.rightAngleNew4-app.rightAngle4>=1)
                    app.rightAngle4=app.rightAngle4+1;
                elseif(app.rightAngleNew4-app.rightAngle4<1)
                    app.rightAngle4=app.rightAngleNew4;
                    app.countI11=1;
                end
            end
            if(app.rightAngleNew4<app.rightAngle4)
                if(app.rightAngle4-app.rightAngleNew4>=1)
                    app.rightAngle4=app.rightAngle4-1;
                elseif(app.rightAngle4-app.rightAngleNew4<1)
                    app.rightAngle4=app.rightAngleNew4;
                    app.countI11=1;
                end
            end
            if(app.rightAngleNew5>=app.rightAngle5)
                if(app.rightAngleNew5-app.rightAngle5>=1)
                    app.rightAngle5=app.rightAngle5+1;
                elseif(app.rightAngleNew5-app.rightAngle5<1)
                    app.rightAngle5=app.rightAngleNew5;
                    app.countI12=1;
                end
            end
            if(app.rightAngleNew5<app.rightAngle5)
                if(app.rightAngle5-app.rightAngleNew5>=1)
                    app.rightAngle5=app.rightAngle5-1;
                elseif(app.rightAngle5-app.rightAngleNew5<1)
                    app.rightAngle5=app.rightAngleNew5;
                    app.countI12=1;
                end
            end
            if(app.rightAngleNew6>=app.rightAngle6)
                if(app.rightAngleNew6-app.rightAngle6>=1)
                    app.rightAngle6=app.rightAngle6+1;
                elseif(app.rightAngleNew6-app.rightAngle6<1)
                    app.rightAngle6=app.rightAngleNew6;
                    app.countI13=1;
                end
            end
            if(app.rightAngleNew6<app.rightAngle6)
                if(app.rightAngle6-app.rightAngleNew6>=1)
                    app.rightAngle6=app.rightAngle6-1;
                elseif(app.rightAngle6-app.rightAngleNew6<1)
                    app.rightAngle6=app.rightAngleNew6;
                    app.countI13=1;
                end
            end
            if(app.rightAngleNew7>=app.rightAngle7)
                if(app.rightAngleNew7-app.rightAngle7>=1)
                    app.rightAngle7=app.rightAngle7+1;
                elseif(app.rightAngleNew7-app.rightAngle7<1)
                    app.rightAngle7=app.rightAngleNew7;
                    app.countI14=1;
                end
            end
            if(app.rightAngleNew7<app.rightAngle7)
                if(app.rightAngle7-app.rightAngleNew7>=1)
                    app.rightAngle7=app.rightAngle7-1;
                elseif(app.rightAngle7-app.rightAngleNew7<1)
                    app.rightAngle7=app.rightAngleNew7;
                    app.countI14=1;
                end
            end
            app.plotGraph();
            drawnow;
        end
        app.StatusLampLabel.Text = 'Finish';
        app.StatusLamp.Color = [0.5882 0.9216 0.5961];
        app.countI1=0;
        app.countI2=0;
        app.countI3=0;
        app.countI4=0;
        app.countI5=0;
        app.countI6=0;
        app.countI7=0;
        app.countI8=0;
        app.countI9=0;
        app.countI10=0;
        app.countI11=0;
        app.countI12=0;
        app.countI13=0;
        app.countI14=0;
        end
    end        
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.plotGraph();
        end

        % Value changed function: Joint1SliderL
        function Joint1SliderLValueChanged(app, event)
value = app.Joint1SliderL.Value;
app.Angle1EditFieldL.Value = value;
        end

        % Value changed function: Joint2SliderL
        function Joint2SliderLValueChanged(app, event)
value = app.Joint2SliderL.Value;
app.Angle2EditFieldL.Value = value;
        end

        % Value changed function: Angle1EditFieldL
        function Angle1TextAreaLValueChanged(app, event)
value = app.Angle1EditFieldL.Value;
app.Joint1SliderL.Value = value;
        end

        % Value changed function: Angle2EditFieldL
        function Angle2TextAreaLValueChanged(app, event)
value = app.Angle2EditFieldL.Value;
app.Joint2SliderL.Value = value;
        end

        % Value changed function: Joint3SliderL
        function Joint3SliderLValueChanged(app, event)
value = app.Joint3SliderL.Value;
app.Angle3EditFieldL.Value = value;
        end

        % Value changed function: Angle3EditFieldL
        function Angle3TextAreaLValueChanged(app, event)
value = app.Angle3EditFieldL.Value;
app.Joint3SliderL.Value = value;
        end

        % Value changed function: Joint4SliderL
        function Joint4SliderLValueChanged(app, event)
value = app.Joint4SliderL.Value;
app.Angle4EditFieldL.Value = value;
        end

        % Value changed function: Angle4EditFieldL
        function Angle4TextAreaLValueChanged(app, event)
value = app.Angle4EditFieldL.Value;
app.Joint4SliderL.Value = value;
        end

        % Value changed function: Joint5SliderL
        function Joint5SliderLValueChanged(app, event)
value = app.Joint5SliderL.Value;
app.Angle5EditFieldL.Value = value;
        end

        % Value changed function: Angle5EditFieldL
        function Angle5TextAreaLValueChanged(app, event)
value = app.Angle5EditFieldL.Value;
app.Joint5SliderL.Value = value;
        end

        % Value changed function: Joint6SliderL
        function Joint6SliderLValueChanged(app, event)
value = app.Joint6SliderL.Value;
app.Angle6EditFieldL.Value = value;
        end

        % Value changed function: Angle6EditFieldL
        function Angle6TextAreaLValueChanged(app, event)
value = app.Angle6EditFieldL.Value;
app.Joint6SliderL.Value = value;
        end

        % Value changed function: Joint7SliderL
        function Joint7SliderLValueChanged(app, event)
value = app.Joint7SliderL.Value;
app.Angle7EditFieldL.Value = value;
        end

        % Value changed function: Angle7EditFieldL
        function Angle7TextAreaLValueChanged(app, event)
value = app.Angle7EditFieldL.Value;
app.Joint7SliderL.Value = value;
        end

        % Value changed function: Joint1SliderR
        function Joint1SliderRValueChanged(app, event)
value = app.Joint1SliderR.Value;
app.Angle1EditFieldR.Value = value;
        end

        % Value changed function: Angle1EditFieldR
        function Angle1TextAreaRValueChanged(app, event)
value = app.Angle1EditFieldR.Value;
app.Joint1SliderR.Value = value;
        end

        % Value changed function: Joint2SliderR
        function Joint2SliderRValueChanged(app, event)
value = app.Joint2SliderR.Value;
app.Angle2EditFieldR.Value = value;
        end

        % Value changed function: Angle2EditFieldR
        function Angle2TextAreaRValueChanged(app, event)
value = app.Angle2EditFieldR.Value;
app.Joint2SliderR.Value = value;
        end

        % Value changed function: Joint3SliderR
        function Joint3SliderRValueChanged(app, event)
value = app.Joint3SliderR.Value;
app.Angle3EditFieldR.Value = value;
        end

        % Value changed function: Angle3EditFieldR
        function Angle3TextAreaRValueChanged(app, event)
value = app.Angle3EditFieldR.Value;
app.Joint3SliderR.Value = value;
        end

        % Value changed function: Joint4SliderR
        function Joint4SliderRValueChanged(app, event)
value = app.Joint4SliderR.Value;
app.Angle4EditFieldR.Value = value;
        end

        % Value changed function: Angle4EditFieldR
        function Angle4TextAreaRValueChanged(app, event)
value = app.Angle4EditFieldR.Value;
app.Joint4SliderR.Value = value;
        end

        % Value changed function: Joint5SliderR
        function Joint5SliderRValueChanged(app, event)
value = app.Joint5SliderR.Value;
app.Angle5EditFieldR.Value = value;
        end

        % Value changed function: Angle5EditFieldR
        function Angle5TextAreaRValueChanged(app, event)
value = app.Angle5EditFieldR.Value;
app.Joint5SliderR.Value = value;
        end

        % Value changed function: Joint6SliderR
        function Joint6SliderRValueChanged(app, event)
value = app.Joint6SliderR.Value;
app.Angle6EditFieldR.Value = value;
        end

        % Value changed function: Angle6EditFieldR
        function Angle6TextAreaRValueChanged(app, event)
value = app.Angle6EditFieldR.Value;
app.Joint6SliderR.Value = value;
        end

        % Value changed function: Joint7SliderR
        function Joint7SliderRValueChanged(app, event)
value = app.Joint7SliderR.Value;
app.Angle7EditFieldR.Value = value;
        end

        % Value changed function: Angle7EditFieldR
        function Angle7TextAreaRValueChanged(app, event)
value = app.Angle7EditFieldR.Value;
app.Joint7SliderR.Value = value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                app.leftAngleNew1   = app.Joint1SliderL.Value;
                app.leftAngleNew2   = app.Joint2SliderL.Value;
                app.leftAngleNew3   = app.Joint3SliderL.Value;
                app.leftAngleNew4   = app.Joint4SliderL.Value;
                app.leftAngleNew5   = app.Joint5SliderL.Value;
                app.leftAngleNew6   = app.Joint6SliderL.Value;
                app.leftAngleNew7   = app.Joint7SliderL.Value;
                app.rightAngleNew1  = app.Joint1SliderR.Value;
                app.rightAngleNew2  = app.Joint2SliderR.Value;
                app.rightAngleNew3  = app.Joint3SliderR.Value;
                app.rightAngleNew4  = app.Joint4SliderR.Value;
                app.rightAngleNew5  = app.Joint5SliderR.Value;
                app.rightAngleNew6  = app.Joint6SliderR.Value;
                app.rightAngleNew7  = app.Joint7SliderR.Value;
                
                app.leftAngleNew2=app.leftAngleNew2*(-1);
                app.rightAngleNew2=app.rightAngleNew2*(-1);
                
                app.updateAngle();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1SliderL = uislider(app.UIFigure);
            app.Joint1SliderL.Limits = [-168.5 168.5];
            app.Joint1SliderL.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderLValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2SliderL = uislider(app.UIFigure);
            app.Joint2SliderL.Limits = [-143.5 43.5];
            app.Joint2SliderL.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderLValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3SliderL = uislider(app.UIFigure);
            app.Joint3SliderL.Limits = [-168.5 168.5];
            app.Joint3SliderL.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderLValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint4';
            app.Joint4SliderL = uislider(app.UIFigure);
            app.Joint4SliderL.Limits = [-123.5 80];
            app.Joint4SliderL.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderLValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint5';
            app.Joint5SliderL = uislider(app.UIFigure);
            app.Joint5SliderL.Limits = [-290 290];
            app.Joint5SliderL.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderLValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint6';
            app.Joint6SliderL = uislider(app.UIFigure);
            app.Joint6SliderL.Limits = [-88 138];
            app.Joint6SliderL.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderLValueChanged, true);
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Text = 'Joint7';
            app.Joint7SliderL = uislider(app.UIFigure);
            app.Joint7SliderL.Limits = [-229 229];
            app.Joint7SliderL.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderLValueChanged, true);
            app.Joint1SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint1SliderLabel_2.Text = 'Joint1';
            app.Joint1SliderR = uislider(app.UIFigure);
            app.Joint1SliderR.Limits = [-168.5 168.5];
            app.Joint1SliderR.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderRValueChanged, true);
            app.Joint2SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint2SliderLabel_2.Text = 'Joint2';
            app.Joint2SliderR = uislider(app.UIFigure);
            app.Joint2SliderR.Limits = [-143.5 43.5];
            app.Joint2SliderR.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderRValueChanged, true);
            app.Joint3SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint3SliderLabel_2.Text = 'Joint3';
            app.Joint3SliderR = uislider(app.UIFigure);
            app.Joint3SliderR.Limits = [-168.5 168.5];
            app.Joint3SliderR.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderRValueChanged, true);
            app.Joint4SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint4SliderLabel_2.Text = 'Joint4';
            app.Joint4SliderR = uislider(app.UIFigure);
            app.Joint4SliderR.Limits = [-123.5 80];
            app.Joint4SliderR.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderRValueChanged, true);
            app.Joint5SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint5SliderLabel_2.Text = 'Joint5';
            app.Joint5SliderR = uislider(app.UIFigure);
            app.Joint5SliderR.Limits = [-290 290];
            app.Joint5SliderR.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderRValueChanged, true);
            app.Joint6SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint6SliderLabel_2.Text = 'Joint6';
            app.Joint6SliderR = uislider(app.UIFigure);
            app.Joint6SliderR.Limits = [-88 138];
            app.Joint6SliderR.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderRValueChanged, true);
            app.Joint7SliderLabel_2 = uilabel(app.UIFigure);
            app.Joint7SliderLabel_2.Text = 'Joint7';
            app.Joint7SliderR = uislider(app.UIFigure);
            app.Joint7SliderR.Limits = [-229 229];
            app.Joint7SliderR.ValueChangedFcn = createCallbackFcn(app, @Joint7SliderRValueChanged, true);
            app.LeftLabel = uilabel(app.UIFigure);
            app.LeftLabel.Text = 'Left';
            app.RightLabel = uilabel(app.UIFigure);
            app.RightLabel.Text = 'Right';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'YUMI')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Angle1EditFieldLabel = uilabel(app.UIFigure);
            app.Angle1EditFieldLabel.Text = 'Angle1';
            app.Angle1EditFieldL = uieditfield(app.UIFigure, 'numeric');
            app.Angle1EditFieldL.Limits = [-168.5 168.5];
            app.Angle1EditFieldL.ValueChangedFcn = createCallbackFcn(app, @Angle1TextAreaLValueChanged, true);
            app.Angle2EditFieldLabel = uilabel(app.UIFigure);
            app.Angle2EditFieldLabel.Text = 'Angle2';
            app.Angle2EditFieldL = uieditfield(app.UIFigure, 'numeric');
            app.Angle2EditFieldL.Limits = [-143.5 43.5];
            app.Angle2EditFieldL.ValueChangedFcn = createCallbackFcn(app, @Angle2TextAreaLValueChanged, true);
            app.Angle3EditFieldLabel = uilabel(app.UIFigure);
            app.Angle3EditFieldLabel.Text = 'Angle3';
            app.Angle3EditFieldL = uieditfield(app.UIFigure, 'numeric');
            app.Angle3EditFieldL.Limits = [-168.5 168.5];
            app.Angle3EditFieldL.ValueChangedFcn = createCallbackFcn(app, @Angle3TextAreaLValueChanged, true);
            app.Angle4EditFieldLabel = uilabel(app.UIFigure);
            app.Angle4EditFieldLabel.Text = 'Angle4';
            app.Angle4EditFieldL = uieditfield(app.UIFigure, 'numeric');
            app.Angle4EditFieldL.Limits = [-123.5 80];
            app.Angle4EditFieldL.ValueChangedFcn = createCallbackFcn(app, @Angle4TextAreaLValueChanged, true);
            app.Angle5EditFieldLabel = uilabel(app.UIFigure);
            app.Angle5EditFieldLabel.Text = 'Angle5';
            app.Angle5EditFieldL = uieditfield(app.UIFigure, 'numeric');
            app.Angle5EditFieldL.Limits = [-290 290];
            app.Angle5EditFieldL.ValueChangedFcn = createCallbackFcn(app, @Angle5TextAreaLValueChanged, true);
            app.Angle6EditFieldLabel = uilabel(app.UIFigure);
            app.Angle6EditFieldLabel.Text = 'Angle6';
            app.Angle6EditFieldL = uieditfield(app.UIFigure, 'numeric');
            app.Angle6EditFieldL.Limits = [-88 138];
            app.Angle6EditFieldL.ValueChangedFcn = createCallbackFcn(app, @Angle6TextAreaLValueChanged, true);
            app.Angle7EditFieldLabel = uilabel(app.UIFigure);
            app.Angle7EditFieldLabel.Text = 'Angle7';
            app.Angle7EditFieldL = uieditfield(app.UIFigure, 'numeric');
            app.Angle7EditFieldL.Limits = [-229 229];
            app.Angle7EditFieldL.ValueChangedFcn = createCallbackFcn(app, @Angle7TextAreaLValueChanged, true);
            app.Angle1EditField_2Label = uilabel(app.UIFigure);
            app.Angle1EditField_2Label.Text = 'Angle1';
            app.Angle1EditFieldR = uieditfield(app.UIFigure, 'numeric');
            app.Angle1EditFieldR.Limits = [-168.5 168.5];
            app.Angle1EditFieldR.ValueChangedFcn = createCallbackFcn(app, @Angle1TextAreaRValueChanged, true);
            app.Angle2EditField_2Label = uilabel(app.UIFigure);
            app.Angle2EditField_2Label.Text = 'Angle2';
            app.Angle2EditFieldR = uieditfield(app.UIFigure, 'numeric');
            app.Angle2EditFieldR.Limits = [-143.5 43.5];
            app.Angle2EditFieldR.ValueChangedFcn = createCallbackFcn(app, @Angle2TextAreaRValueChanged, true);
            app.Angle3EditField_2Label = uilabel(app.UIFigure);
            app.Angle3EditField_2Label.Text = 'Angle3';
            app.Angle3EditFieldR = uieditfield(app.UIFigure, 'numeric');
            app.Angle3EditFieldR.Limits = [-168.5 168.5];
            app.Angle3EditFieldR.ValueChangedFcn = createCallbackFcn(app, @Angle3TextAreaRValueChanged, true);
            app.Angle4EditField_2Label = uilabel(app.UIFigure);
            app.Angle4EditField_2Label.Text = 'Angle4';
            app.Angle4EditFieldR = uieditfield(app.UIFigure, 'numeric');
            app.Angle4EditFieldR.Limits = [-123.5 80];
            app.Angle4EditFieldR.ValueChangedFcn = createCallbackFcn(app, @Angle4TextAreaRValueChanged, true);
            app.Angle5EditField_2Label = uilabel(app.UIFigure);
            app.Angle5EditField_2Label.Text = 'Angle5';
            app.Angle5EditFieldR = uieditfield(app.UIFigure, 'numeric');
            app.Angle5EditFieldR.Limits = [-290 290];
            app.Angle5EditFieldR.ValueChangedFcn = createCallbackFcn(app, @Angle5TextAreaRValueChanged, true);
            app.Angle6EditField_2Label = uilabel(app.UIFigure);
            app.Angle6EditField_2Label.Text = 'Angle6';
            app.Angle6EditFieldR = uieditfield(app.UIFigure, 'numeric');
            app.Angle6EditFieldR.Limits = [-88 138];
            app.Angle6EditFieldR.ValueChangedFcn = createCallbackFcn(app, @Angle6TextAreaRValueChanged, true);
            app.Angle7EditField_2Label = uilabel(app.UIFigure);
            app.Angle7EditField_2Label.Text = 'Angle7';
            app.Angle7EditFieldR = uieditfield(app.UIFigure, 'numeric');
            app.Angle7EditFieldR.Limits = [-229 229];
            app.Angle7EditFieldR.ValueChangedFcn = createCallbackFcn(app, @Angle7TextAreaRValueChanged, true);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StatusLamp.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final6013150

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end